classdef microperimetry_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OptionsPanel              matlab.ui.container.Panel
        EyeSideButtonGroup        matlab.ui.container.ButtonGroup
        ODButton                  matlab.ui.control.ToggleButton
        OSButton                  matlab.ui.control.ToggleButton
        LoadDataButton            matlab.ui.control.Button
        SaveFigureAsButton        matlab.ui.control.StateButton
        DisplayValuesSwitchLabel  matlab.ui.control.Label
        DisplayValuesSwitch       matlab.ui.control.Switch
        DisplayPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        axes
        data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = MicroperimetryData(15*rand(17,2));%"coordinates.csv");
            
            ax = MicroperimetryAxesArray(app.DisplayPanel, d);
            ax.row_titles = ["Mesopic" "Scotopic"];
            ax.col_titles = ["Case" "Group Means" "Z-Scores"];
            ax.build();
            
            app.axes = ax;
            app.data = d;
        end

        % Selection changed function: EyeSideButtonGroup
        function EyeSideButtonGroupSelectionChanged(app, event)
            selectedButton = app.EyeSideButtonGroup.SelectedObject;
            
        end

        % Callback function
        function ValueDisplayStateButtonValueChanged(app, event)
            value = app.ValueDisplayStateButton.Value;
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile("*.csv", "Load CSV microperimetry data file");
            path = fullfile(path, file);
            try
                app.data.load_csv(path);
            catch e
                uialert(app.UIFigure, ["Error loading file", e.message]);
            end
            app.axes.update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.EyeSideButtonGroup = uibuttongroup(app.OptionsPanel);
            app.EyeSideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSideButtonGroupSelectionChanged, true);
            app.EyeSideButtonGroup.Title = 'Eye Side';
            app.ODButton = uitogglebutton(app.EyeSideButtonGroup);
            app.ODButton.Text = 'OD';
            app.ODButton.Value = true;
            app.OSButton = uitogglebutton(app.EyeSideButtonGroup);
            app.OSButton.Text = 'OS';
            app.LoadDataButton = uibutton(app.OptionsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data...';
            app.SaveFigureAsButton = uibutton(app.OptionsPanel, 'state');
            app.SaveFigureAsButton.Text = 'Save figure as...';
            app.DisplayValuesSwitchLabel = uilabel(app.OptionsPanel);
            app.DisplayValuesSwitchLabel.Text = 'Display Values';
            app.DisplayValuesSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microperimetry_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end